classdef specGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MeasurementTypeSwitchLabel     matlab.ui.control.Label
        MeasurementTypeSwitch          matlab.ui.control.Switch
        TakeMeasurementButton          matlab.ui.control.Button
        BlankButton                    matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        BallOutButton                  matlab.ui.control.Button
        ExportDataButton               matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UploadCalibrationDataButton    matlab.ui.control.Button
        ExtinctionCoefficientLabel     matlab.ui.control.Label
        ExtinctionCoefficientTextArea  matlab.ui.control.TextArea
        InputConcentrationMEditFieldLabel  matlab.ui.control.Label
        InputConcentrationMEditField   matlab.ui.control.NumericEditField
        CollectCalibrationDataButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        concentration_calib = []; % Concentration data to plot on the axes
        absorbance_calib = []; % Absorbance data to plot on the axes
        extinction_coeff = 0; % Molar extinction coefficient
        blank_voltage_abs = -1; % Blank voltage for absorbance
        blank_voltage_flu = -1; % Blank voltage for fluorescence
        ard = arduino('COM3', 'Uno'); %arduino connection before start
        %NOTE: make sure ports are correct
        % Table data to cotinually update - column vectors
        read_number = [];
        raw_voltage = []; 
        absorbance = [];
        concentration = [];
        fluorescence = [];
        variablenames = {'Read #', 'Raw Voltage', 'Absorbance', 'Concentration', 'Fluorescence'};
    end
    
    methods (Access = private)
        %function plots the absorbance and concentration values on axes and,
        %if there is more than one point calculates extiction coefficient
        function plotter(app)
            semilogx(app.UIAxes, app.concentration_calib, app.absorbance_calib, 'o--')
            model = fitlm(app.concentration_calib, app.absorbance_calib, 'linear');
            app.extinction_coeff = model.Coefficients{2,1};
            app.ExtinctionCoefficientTextArea.Value = {num2str(app.extinction_coeff, 4)};                
        end
        
        function updateTable(app, number, volts, abs, conc, flu)
            %function updates the table with the following entries
            app.read_number = vertcat(app.read_number, number);
            app.raw_voltage = vertcat(app.raw_voltage, volts);
            app.absorbance = vertcat(app.absorbance, abs);
            app.concentration = vertcat(app.concentration, conc);
            app.fluorescence = vertcat(app.fluorescence, flu);
            app.UITable.Data = table(app.read_number, app.raw_voltage, ...
                app.absorbance, app.concentration, app.fluorescence);
        end
    end
    

    methods (Access = private)

        % Button pushed function: UploadCalibrationDataButton
        function UploadCalibrationDataButtonPushed(app, event)
            file = uigetfile('*.xlsx','Select an excel file with concentration & absorbance data');
            raw = xlsread(file);
            app.concentration_calib = raw(:,1);
            app.absorbance_calib = raw(:,2);
            plotter(app)
            semilogx(app.UIAxes, app.concentration_calib, app.absorbance_calib, 'o--')
            model = polyfit(app.concentration_calib, app.absorbance_calib, 1);
            app.extinction_coeff = model(1);
            app.ExtinctionCoefficientTextArea.Value = {num2str(app.extinction_coeff, 4)}; 
        end

        % Button pushed function: BlankButton
        function BlankButtonPushed(app, event)
            %Blank both or once at a time?
            app.blank_voltage_abs = readVoltage(app.ard, 'A0');
            app.blank_voltage_flu = readVoltage(app.ard, 'A0');
        end

        % Button pushed function: CollectCalibrationDataButton
        function CollectCalibrationDataButtonPushed(app, event)
            if (app.blank_voltage_abs == -1)
                errordlg("Please blank the spectrophotometer");
            else
                volts_in = readVoltage(app.ard, 'A0');
                conc_in = app.InputConcentrationMEditField.Value;
                abs_in = -log10(volts_in / app.blank_voltage_abs);
                app.absorbance_calib = horzcat(app.absorbance_calib, abs_in);
                app.concentration_calib = horzcat(app.concentration_calib, conc_in);
                plotter(app);
            end
        end

        % Button pushed function: TakeMeasurementButton
        function TakeMeasurementButtonPushed(app, event)
            if strcmp(app.MeasurementTypeSwitch.Value, 'Absorbance')
                if (app.blank_voltage_abs == -1)
                    errordlg("Please blank the spectrophotometer");
                elseif (app.extinction_coeff == 0)
                    errdlg("Please calibrate to provide extinction coefficient")
                else
                    volt_in = readVoltage(app.ard, 'A0');
                    abs_in = -log10(volt_in / app.blank_voltage_abs);
                    conc_in = abs_in / app.extinction_coeff;
                    sample_in = length(app.read_number) + 1;
                    flu_in = nan;
                    updateTable(app, sample_in, volt_in, abs_in, conc_in, flu_in)
                end
            else
                if (app.blank_voltage_flu == -1)
                    errordlg("Please blank the spectrophotometer");
                else
                    volt_in = nan;
                    abs_in = nan;
                    conc_in = nan;
                    sample_in = length(app.read_number) + 1;
                    flu_in = readVoltage(app.ard, 'A0'); %uhhh
                    updateTable(app, sample_in, volt_in, abs_in, conc_in, flu_in)
                end
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            [file, path] = uiputfile('myData.xlsx', '*.xlsx');
            output = strcat(path, file);
            %join data and titles and write to excel
            data = num2cell(horzcat(app.read_number, app.raw_voltage, ...
                app.absorbance, app.concentration, app.fluorescence));
            combined = [app.variablenames; data];
            xlswrite(output, combined);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MeasurementTypeSwitchLabel = uilabel(app.UIFigure);
            app.MeasurementTypeSwitchLabel.Text = 'Measurement Type';
            app.MeasurementTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.MeasurementTypeSwitch.Items = {'Absorbance', 'Fluorescence'};
            app.MeasurementTypeSwitch.Value = 'Absorbance';
            app.TakeMeasurementButton = uibutton(app.UIFigure, 'push');
            app.TakeMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @TakeMeasurementButtonPushed, true);
            app.TakeMeasurementButton.Text = 'Take Measurement';
            app.BlankButton = uibutton(app.UIFigure, 'push');
            app.BlankButton.ButtonPushedFcn = createCallbackFcn(app, @BlankButtonPushed, true);
            app.BlankButton.Text = 'Blank';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Read #'; 'Raw Voltage'; 'Absorbance'; 'Concentration'; 'Fluorescence'};
            app.UITable.RowName = {};
            app.BallOutButton = uibutton(app.UIFigure, 'push');
            app.BallOutButton.Text = 'Ball Out';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Calibration Curve')
            xlabel(app.UIAxes, 'Concentration (M)')
            ylabel(app.UIAxes, 'Absorbance')
            app.UploadCalibrationDataButton = uibutton(app.UIFigure, 'push');
            app.UploadCalibrationDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCalibrationDataButtonPushed, true);
            app.UploadCalibrationDataButton.Text = 'Upload Calibration Data';
            app.ExtinctionCoefficientLabel = uilabel(app.UIFigure);
            app.ExtinctionCoefficientLabel.Text = 'Extinction Coefficient:';
            app.ExtinctionCoefficientTextArea = uitextarea(app.UIFigure);
            app.ExtinctionCoefficientTextArea.Value = {'0'};
            app.InputConcentrationMEditFieldLabel = uilabel(app.UIFigure);
            app.InputConcentrationMEditFieldLabel.Text = 'Input Concentration (M):';
            app.InputConcentrationMEditField = uieditfield(app.UIFigure, 'numeric');
            app.CollectCalibrationDataButton = uibutton(app.UIFigure, 'push');
            app.CollectCalibrationDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectCalibrationDataButtonPushed, true);
            app.CollectCalibrationDataButton.Text = 'Collect Calibration Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = specGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end